classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        SELECTAUDIODropDown_2Label  matlab.ui.control.Label
        SELECTAUDIODropDown         matlab.ui.control.DropDown
        stopButton                  matlab.ui.control.Button
        resumeButton                matlab.ui.control.Button
        pauseButton                 matlab.ui.control.Button
        playButton                  matlab.ui.control.Button
        VOLUMELabel_2               matlab.ui.control.Label
        volumeKnob                  matlab.ui.control.Knob
        PLAYBACKSPEEDLabel          matlab.ui.control.Label
        playbackKnob                matlab.ui.control.Knob
        SELECTFILTERDropDownLabel   matlab.ui.control.Label
        SELECTFILTERDropDown        matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            global audioFile;
            stop(audioFile);
            play(audioFile);
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            global audioFile;
            pause(audioFile);
        end

        % Button pushed function: resumeButton
        function resumeButtonPushed(app, event)
            global audioFile;
            resume(audioFile);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            global audioFile;
            stop(audioFile);
        end

        % Value changed function: SELECTAUDIODropDown
        function SELECTAUDIODropDownValueChanged(app, event)
            global audioFile; %variable stores the audioFile that will be played
            global file;%variable stores the file
            global volumeChanged;
            global volume;
            global speedChanged;
            global speed;
            
            file = app.SELECTAUDIODropDown.Value;
            [y, Fs] = audioread(file);
            audioFile = audioplayer(y,Fs);%set the global variable for audioFile to the selected audio file
                 
            %plot the signal in time domain
            Mono = mean(y,2);
            t = linspace(0, length(y)/Fs,length(y));
            plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
            %plot the signal in frequency domain            
            nfft = 1024;
            f = linspace(0, Fs, nfft);
            Y = abs(fft(y,nfft));
            plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            
            
            % if the volume was already set to a value other than 100 to
            % begin with, but the playback knob was untouched
            if(volumeChanged == true)
                y = y * volume;
                audioFile = audioplayer(y, Fs);%scale y by the volume
            
                %plot the signal in time domain
                Mono = mean(y,2);
                t = linspace(0, length(y)/Fs,length(y));
                plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
                %plot the signal in frequency domain            
                nfft = 1024;
                f = linspace(0, Fs, nfft);
                Y = abs(fft(y,nfft));
                plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            end
            

            %if the playback speed was already set to a value other than 1
            %to begin with, but the volume knob was untouched
            if(speedChanged == true)
                Fs = Fs * speed;
                audioFile = audioplayer(y, Fs);%scale y by the volume
            
                %plot the signal in time domain
                Mono = mean(y,2);
                t = linspace(0, length(y)/Fs,length(y));
                plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
                %plot the signal in frequency domain            
                nfft = 1024;
                f = linspace(0, Fs, nfft);
                Y = abs(fft(y,nfft));
                plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            end
            
            
            %if both the playback speed and volume knobs were shifted
            if(speedChanged == true && volumeChanged == true)
                Fs = Fs * speed;
                y = y * volume;
                audioFile = audioplayer(y, Fs);
            
                %plot the signal in time domain
                Mono = mean(y,2);
                t = linspace(0, length(y)/Fs,length(y));
                plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
                %plot the signal in frequency domain            
                nfft = 1024;
                f = linspace(0, Fs, nfft);
                Y = abs(fft(y,nfft));
                plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear global %will clear all of the global variable declarations
            clearvars -global;
            delete(app)
        end

        % Value changed function: volumeKnob
        function volumeKnobValueChanged(app, event)
            global audioFile;
            global file;
            global volumeChanged;
            global volume;
            global speed;
            global speedChanged;
            
            volumeChanged = true;

            volume = app.volumeKnob.Value /100;%this gets the volume value from the knob
            
            %scale the audio file's values by the volume scalar
            [y, Fs] = audioread(file);
            y = y * volume;%scale y by the volume
            if(speedChanged == true)%scale the Fs if there was a speed change as well
                Fs =  Fs * speed;
            end
            audioFile = audioplayer(y, Fs);
            
            %plot the signal in time domain
            Mono = mean(y,2);
            t = linspace(0, length(y)/Fs,length(y));
            plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
            %plot the signal in frequency domain            
            nfft = 1024;
            f = linspace(0, Fs, nfft);
            Y = abs(fft(y,nfft));
            plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));            
            
        end

        % Value changed function: playbackKnob
        function playbackKnobValueChanged(app, event)
            global audioFile;
            global file;
            global volume
            global volumeChanged;
            global speed;
            global speedChanged;
            
            speedChanged = true;
            
            speed = app.playbackKnob.Value;%this gets the playback speed value from the knob
            
            %scale the audio file's values by the speed scalar
            [y, Fs]  =audioread(file);
            Fs = Fs * speed;%scale the sampling frequency by the speed
            if(volumeChanged == true)%scale the y if there was a volume change as well
                y  =y * volume;
            end
            audioFile = audioplayer(y, Fs);
            
            %plot the signal in time domain
            Mono = mean(y,2);
            t = linspace(0, length(y)/Fs,length(y));
            plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
            %plot the signal in frequency domain            
            nfft = 1024;
            f = linspace(0, Fs, nfft);
            Y = abs(fft(y,nfft));
            plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));            
        end

        % Value changed function: SELECTFILTERDropDown
        function SELECTFILTERDropDownValueChanged(app, event)
            global audioFile; %variable stores the audioFile that will be played
            global file;%variable stores the file
            global volumeChanged;
            global volume;
            global speedChanged;
            global speed;
            value = app.SELECTFILTERDropDown.Value;
            [y, Fs] = audioread(file);
           
            
            Y = fft(y);
            N = length(y);
            f = (-Fs/2 : Fs/(N - 1) : Fs/2);
            
            
            if (value == "Low Pass Filter")
                Z = Y.*lowpass(f, 0.3);
                z = real(ifft(Z));          
            elseif (value == "High Pass Filter")
                Z = Y.*lowpass(f, 0.7);
                z = real(ifft(Z));
            elseif (value == "Bandpass Filter")
                Z = Y.* BPF(f, 0.4, 0.5);
                z = real(ifft(Z));
            end
            
            y = z;
            
            audioFile = audioplayer(y, Fs);   
            
            if(volumeChanged == true)
                y = y * volume;
                audioFile = audioplayer(y, Fs);%scale y by the volume
            
                %plot the signal in time domain
                Mono = mean(y,2);
                t = linspace(0, length(y)/Fs,length(y));
                plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
                %plot the signal in frequency domain            
                nfft = 1024;
                f = linspace(0, Fs, nfft);
                Y = abs(fft(y,nfft));
                plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            end
            

            %if the playback speed was already set to a value other than 1
            %to begin with, but the volume knob was untouched
            if(speedChanged == true)
                Fs = Fs * speed;
                audioFile = audioplayer(y, Fs);%scale y by the volume
            
                %plot the signal in time domain
                Mono = mean(y,2);
                t = linspace(0, length(y)/Fs,length(y));
                plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
                %plot the signal in frequency domain            
                nfft = 1024;
                f = linspace(0, Fs, nfft);
                Y = abs(fft(y,nfft));
                plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            end
            
            
            %if both the playback speed and volume knobs were shifted
            if(speedChanged == true && volumeChanged == true)
                Fs = Fs * speed;
                y = y * volume;
                audioFile = audioplayer(y, Fs);
            
                %plot the signal in time domain
                Mono = mean(y,2);
                t = linspace(0, length(y)/Fs,length(y));
                plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
                %plot the signal in frequency domain            
                nfft = 1024;
                f = linspace(0, Fs, nfft);
                Y = abs(fft(y,nfft));
                plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            end
            
            %plot the signal in time domain
            Mono = mean(y,2);
            t = linspace(0, length(y)/Fs,length(y));
            plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
            %plot the signal in frequency domain            
            nfft = 1024;
            f = linspace(0, Fs, nfft);
            Y = abs(fft(y,nfft));
            plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.SELECTAUDIODropDown_2Label = uilabel(app.Panel);
            app.SELECTAUDIODropDown_2Label.Text = 'SELECT AUDIO';
            app.SELECTAUDIODropDown = uidropdown(app.Panel);
            app.SELECTAUDIODropDown.Items = {'', 'All I Want for Christmas.mp3', 'Fight Song.mp3', 'Motivational Speech.mp3', 'Police Siren.mp3'};
            app.SELECTAUDIODropDown.ValueChangedFcn = createCallbackFcn(app, @SELECTAUDIODropDownValueChanged, true);
            app.SELECTAUDIODropDown.Value = '';
            app.stopButton = uibutton(app.Panel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'STOP';
            app.resumeButton = uibutton(app.Panel, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resumeButton.Text = 'RESUME';
            app.pauseButton = uibutton(app.Panel, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'PAUSE';
            app.playButton = uibutton(app.Panel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'PLAY';
            app.VOLUMELabel_2 = uilabel(app.Panel);
            app.VOLUMELabel_2.Text = 'VOLUME';
            app.volumeKnob = uiknob(app.Panel, 'continuous');
            app.volumeKnob.Limits = [0 500];
            app.volumeKnob.ValueChangedFcn = createCallbackFcn(app, @volumeKnobValueChanged, true);
            app.volumeKnob.Value = 100;
            app.PLAYBACKSPEEDLabel = uilabel(app.Panel);
            app.PLAYBACKSPEEDLabel.Text = 'PLAYBACK SPEED';
            app.playbackKnob = uiknob(app.Panel, 'continuous');
            app.playbackKnob.Limits = [0.25 2];
            app.playbackKnob.ValueChangedFcn = createCallbackFcn(app, @playbackKnobValueChanged, true);
            app.playbackKnob.Value = 1;
            app.SELECTFILTERDropDownLabel = uilabel(app.Panel);
            app.SELECTFILTERDropDownLabel.Text = 'SELECT FILTER';
            app.SELECTFILTERDropDown = uidropdown(app.Panel);
            app.SELECTFILTERDropDown.Items = {'', 'Low Pass Filter', 'High Pass Filter', 'Bandpass Filter'};
            app.SELECTFILTERDropDown.ValueChangedFcn = createCallbackFcn(app, @SELECTFILTERDropDownValueChanged, true);
            app.SELECTFILTERDropDown.Value = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'TIME (s)')
            ylabel(app.UIAxes, 'AMPLITUDE')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency Domain')
            xlabel(app.UIAxes_2, 'FREQUENCY (Hz)')
            ylabel(app.UIAxes_2, 'POWER SPECTRUM (dB)')
            zlabel(app.UIAxes_2, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.playButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end